classdef FEM_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        ScaleSlider              matlab.ui.control.Slider
        ScaleSliderLabel         matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        RemoveButton             matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        ApplyButton              matlab.ui.control.Button
        MagnitudeEditField       matlab.ui.control.NumericEditField
        MagnitudeEditFieldLabel  matlab.ui.control.Label
        AxisSwitch               matlab.ui.control.Switch
        AxisSwitchLabel          matlab.ui.control.Label
        BoundaryConditionSwitch  matlab.ui.control.ToggleSwitch
        BoundaryConditionLabel   matlab.ui.control.Label
        NodeSpinner              matlab.ui.control.Spinner
        NodeSpinnerLabel         matlab.ui.control.Label
        StyleButtonGroup         matlab.ui.container.ButtonGroup
        Cool                     matlab.ui.control.ToggleButton
        Winter                   matlab.ui.control.ToggleButton
        Bone                     matlab.ui.control.ToggleButton
        Parula                   matlab.ui.control.ToggleButton
        Jet                      matlab.ui.control.ToggleButton
        ImportfromExcelButton    matlab.ui.control.Button
        NodeLabelSwitch          matlab.ui.control.Switch
        NodeLabelSwitchLabel     matlab.ui.control.Label
        ElementListLabel         matlab.ui.control.Label
        EL_Table                 matlab.ui.control.Table
        GenerateElementButton    matlab.ui.control.Button
        ElementLabel             matlab.ui.control.Label
        Node2                    matlab.ui.control.NumericEditField
        Node2EditFieldLabel      matlab.ui.control.Label
        Node1                    matlab.ui.control.NumericEditField
        Node1EditFieldLabel      matlab.ui.control.Label
        NodeListLabel            matlab.ui.control.Label
        NL_Table                 matlab.ui.control.Table
        GenerateNodeButton       matlab.ui.control.Button
        NodeLabel                matlab.ui.control.Label
        Y_coord                  matlab.ui.control.NumericEditField
        YLabel                   matlab.ui.control.Label
        X_coord                  matlab.ui.control.NumericEditField
        XLabel                   matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        NL
        EL
        NoN = 1
        NoE
        scale = 10
        NodeSwitch = "On"
        colorMap = jet
        sortedNew
        sortedExisting
        SelectedNode
        Direction
        BC
        Magnitude
    end
    
    methods (Access = public)
        
        function results = updateAxes(app)

            cla(app.UIAxes);
            
            colormap(app.UIAxes, app.colorMap);
            colorbar(app.UIAxes);

            hold(app.UIAxes,"on");

           for i = 1 : app.NoE
                H = plot(app.UIAxes,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], "LineWidth", 3, "Color", "w");
                H.Color(4) = 0.2; % alpha = 0.2

           end

           for i = 1 : app.NoN
                plot(app.UIAxes,app.NL(i,1), app.NL(i,2), 'o', 'MarkerSize',app.scale, 'MarkerFaceColor', 'r')
           end
            
           switch app.NodeSwitch

               case "On"

                    for i = 1:app.NoN

                    text(app.UIAxes,app.NL(i,1),app.NL(i,2),num2str(i),"Color","w","FontSize",12,"HorizontalAlignment","center")
                
                    end
           end


        end
        function applyGradient(~, targetButton, startCol, endCol)
            % Create a smooth 100-pixel gradient
            cols = 100; rows = 20;
            
            % Linear interpolation for R, G, and B channels
            r = linspace(startCol(1), endCol(1), cols);
            g = linspace(startCol(2), endCol(2), cols);
            b = linspace(startCol(3), endCol(3), cols);
            
            % Combine into an RGB image matrix
            gradRow = reshape([r; g; b]', [1, cols, 3]);
            iconMatrix = repmat(gradRow, [rows, 1, 1]);
            
            % Assign to button
            targetButton.Icon = iconMatrix;

            % Center
            targetButton.IconAlignment = 'center';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Button gradients
            app.applyGradient(app.GenerateNodeButton, [0.2 0 0.2], [1 0 1])
            app.applyGradient(app.GenerateElementButton, [0 0 0.4], [0 0 1]);
            app.applyGradient(app.ImportfromExcelButton, [0.6 0.6 0.6], [0 0.5 0]);
            app.applyGradient(app.ApplyButton, [0.2 0.2 0.2], [0.0 0.6 0.2]);
            app.applyGradient(app.RemoveButton, [0.3, 0, 0], [1, 0, 0]);
            app.applyGradient(app.ResetButton, [0.2 0.1 0], [0.8 0.5 0]);

        end

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            nl = [app.X_coord.Value, app.Y_coord.Value];
            
            if ~isempty(app.NL) && ismember(nl, app.NL, "rows")

                uialert(app.UIFigure, "The node at ("+num2str(app.X_coord.Value)+","+num2str(app.Y_coord.Value)+") already exists!", "Duplicate Entry")
                return

            end

            app.NL = [app.NL; nl];

            app.NL_Table.Data = app.NL;
            
            app.NoN = size(app.NL, 1);
            
            updateAxes(app);
            
        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
           el = [app.Node1.Value, app.Node2.Value];

           if app.NoN < 2

               uialert(app.UIFigure,"At least 2 nodes are needed to create an element! You have: " + app.NoN, "Input Error")
               
               return

           elseif app.Node1.Value == app.Node2.Value

               uialert(app.UIFigure, "An element needs two different nodes to be generated!", "Duplicate Entry")
           
                return
           end
           
           if ~isempty(app.EL)
                app.sortedNew = sort(el);
                app.sortedExisting = sort(app.EL, 2);
           

                if ismember(app.sortedNew, app.sortedExisting, "rows")

                    uialert(app.UIFigure, "The element between nodes ("+num2str(app.Node1.Value)+","+num2str(app.Node2.Value)+") already exists!", "Duplicate Entry")
                    return
                end
           else
    
               app.EL = [app.EL; el];
    
               app.EL_Table.Data = app.EL;
    
               app.NoE = size(app.EL,1);
    
               updateAxes(app);
           
           end
        end

        % Callback function
        function ScaleSliderValueChanged(app, event)


        end

        % Value changed function: ScaleSlider
        function ScaleSliderValueChanged2(app, event)
            app.scale = app.ScaleSlider.Value;
            
            updateAxes(app);
            
        end

        % Value changed function: NodeLabelSwitch
        function NodeLabelSwitchValueChanged(app, event)
            app.NodeSwitch = app.NodeLabelSwitch.Value;
            
            updateAxes(app);

        end

        % Selection changed function: StyleButtonGroup
        function StyleButtonGroupSelectionChanged(app, event)
            SelectedButton = app.StyleButtonGroup.SelectedObject;
            
            switch SelectedButton

                case app.Jet

                    app.colorMap = jet;
                    colormap(app.UIAxes, app.colorMap);

                case app.Parula

                    app.colorMap = parula;
                    colormap(app.UIAxes, app.colorMap);

                case app.Bone

                    app.colorMap = bone;
                    colormap(app.UIAxes, app.colorMap);

                case app.Winter

                    app.colorMap = winter;
                    colormap(app.UIAxes, app.colorMap);

                case app.Cool

                    app.colorMap = cool;
                    colormap(app.UIAxes, app.colorMap);

                    
            end
        end

        % Button pushed function: ImportfromExcelButton
        function ImportfromExcelButtonPushed(app, event)
            [inputfile, pathname] = uigetfile({"*.xlsx";"*.slx"}, "Select file to open");
    
            if ~isequal(inputfile, 0)

                fullPath = fullfile(pathname, inputfile);
                
  
                nl_raw = readmatrix(fullPath, "Range", "A:B");
                el_raw = readmatrix(fullPath, "Range", "C:D");
                

                app.NL = nl_raw(~any(isnan(nl_raw), 2), :);
                app.EL = el_raw(~any(isnan(el_raw), 2), :);
                

                app.NL_Table.Data = app.NL;
                app.EL_Table.Data = app.EL;
                

                app.NoN = size(app.NL, 1);
                app.NoE = size(app.EL, 1);
                

                updateAxes(app);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: NodeSpinner
        function NodeSpinnerValueChanged(app, event)
            app.SelectedNode = app.NodeSpinner.Value;
            app.NodeSpinner.Limits = [1, app.NoN];
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
        end

        % Value changed function: MagnitudeEditField
        function MagnitudeEditFieldValueChanged(app, event)
            app.Magnitude = app.MagnitudeEditField.Value;
            
        end

        % Value changed function: BoundaryConditionSwitch
        function BoundaryConditionSwitchValueChanged(app, event)
            app.BC = app.BoundaryConditionSwitch.Value;

        end

        % Value changed function: AxisSwitch
        function AxisSwitchValueChanged(app, event)
            app.Direction = app.AxisSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Pre-Processing')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.X_coord = uieditfield(app.GridLayout, 'numeric');
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'Y';
            app.Y_coord = uieditfield(app.GridLayout, 'numeric');
            app.NodeLabel = uilabel(app.GridLayout);
            app.NodeLabel.Text = 'Node';
            app.GenerateNodeButton = uibutton(app.GridLayout, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = {'Generate'; 'Node'};
            app.NL_Table = uitable(app.GridLayout);
            app.NL_Table.ColumnName = {'X'; 'Y'};
            app.NL_Table.RowName = {};
            app.NodeListLabel = uilabel(app.GridLayout);
            app.NodeListLabel.Text = 'Node List';
            app.Node1EditFieldLabel = uilabel(app.GridLayout);
            app.Node1EditFieldLabel.Text = 'Node 1';
            app.Node1 = uieditfield(app.GridLayout, 'numeric');
            app.Node1.Value = 1;
            app.Node2EditFieldLabel = uilabel(app.GridLayout);
            app.Node2EditFieldLabel.Text = 'Node 2';
            app.Node2 = uieditfield(app.GridLayout, 'numeric');
            app.Node2.Value = 2;
            app.ElementLabel = uilabel(app.GridLayout);
            app.ElementLabel.Text = 'Element';
            app.GenerateElementButton = uibutton(app.GridLayout, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = {'Generate'; 'Element'};
            app.EL_Table = uitable(app.GridLayout);
            app.EL_Table.ColumnName = {'Node 1'; 'Node 2'};
            app.EL_Table.RowName = {};
            app.ElementListLabel = uilabel(app.GridLayout);
            app.ElementListLabel.Text = 'Element List';
            app.NodeLabelSwitchLabel = uilabel(app.GridLayout);
            app.NodeLabelSwitchLabel.Text = 'Node Label';
            app.NodeLabelSwitch = uiswitch(app.GridLayout, 'slider');
            app.NodeLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelSwitchValueChanged, true);
            app.NodeLabelSwitch.Value = 'On';
            app.ImportfromExcelButton = uibutton(app.GridLayout, 'push');
            app.ImportfromExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfromExcelButtonPushed, true);
            app.ImportfromExcelButton.Text = 'Import from Excel';
            app.StyleButtonGroup = uibuttongroup(app.GridLayout);
            app.StyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StyleButtonGroupSelectionChanged, true);
            app.StyleButtonGroup.Title = 'Style';
            app.Jet = uitogglebutton(app.StyleButtonGroup);
            app.Jet.Icon = fullfile(pathToMLAPP, 'colormap_jet.png');
            app.Jet.Text = '';
            app.Jet.Value = true;
            app.Parula = uitogglebutton(app.StyleButtonGroup);
            app.Parula.Icon = fullfile(pathToMLAPP, 'colormap_parula_update17a.png');
            app.Parula.Text = '';
            app.Bone = uitogglebutton(app.StyleButtonGroup);
            app.Bone.Icon = fullfile(pathToMLAPP, 'colormap_bone.png');
            app.Bone.Text = '';
            app.Winter = uitogglebutton(app.StyleButtonGroup);
            app.Winter.Icon = fullfile(pathToMLAPP, 'colormap_winter.png');
            app.Winter.Text = '';
            app.Cool = uitogglebutton(app.StyleButtonGroup);
            app.Cool.Icon = fullfile(pathToMLAPP, 'colormap_cool.png');
            app.Cool.Text = '';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.NodeSpinnerLabel = uilabel(app.GridLayout2);
            app.NodeSpinnerLabel.Text = 'Node';
            app.NodeSpinner = uispinner(app.GridLayout2);
            app.NodeSpinner.ValueChangedFcn = createCallbackFcn(app, @NodeSpinnerValueChanged, true);
            app.NodeSpinner.Value = 1;
            app.BoundaryConditionLabel = uilabel(app.GridLayout2);
            app.BoundaryConditionLabel.Text = {'Boundary'; 'Condition'};
            app.BoundaryConditionSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.BoundaryConditionSwitch.Items = {'Force', 'Displacement'};
            app.BoundaryConditionSwitch.ItemsData = {'0', '1'};
            app.BoundaryConditionSwitch.ValueChangedFcn = createCallbackFcn(app, @BoundaryConditionSwitchValueChanged, true);
            app.BoundaryConditionSwitch.Value = '0';
            app.AxisSwitchLabel = uilabel(app.GridLayout2);
            app.AxisSwitchLabel.Text = 'Axis';
            app.AxisSwitch = uiswitch(app.GridLayout2, 'slider');
            app.AxisSwitch.Items = {'X', 'Y'};
            app.AxisSwitch.ValueChangedFcn = createCallbackFcn(app, @AxisSwitchValueChanged, true);
            app.AxisSwitch.Value = 'X';
            app.MagnitudeEditFieldLabel = uilabel(app.GridLayout2);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MagnitudeEditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.GridLayout2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RemoveButton = uibutton(app.GridLayout2, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ScaleSliderLabel = uilabel(app.GridLayout);
            app.ScaleSliderLabel.Text = 'Scale';
            app.ScaleSlider = uislider(app.GridLayout);
            app.ScaleSlider.Limits = [1 100];
            app.ScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSliderValueChanged2, true);
            app.ScaleSlider.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEM_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end